classdef CR250interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CR250interactiveUIFigure        matlab.ui.Figure
        MaintenanceMenu                 matlab.ui.container.Menu
        compileMexDriverMenuItem        matlab.ui.container.Menu
        DeviceConfigMenu                matlab.ui.container.Menu
        openCR250MenuItem               matlab.ui.container.Menu
        verbosity                       matlab.ui.container.Menu
        minimumMenu                     matlab.ui.container.Menu
        defaultMenu                     matlab.ui.container.Menu
        maxMenu                         matlab.ui.container.Menu
        syncMenuItem                    matlab.ui.container.Menu
        noneSyncMenuItem                matlab.ui.container.Menu
        manualSyncMenuItem              matlab.ui.container.Menu
        enterfrequencyHzMenuItem        matlab.ui.container.Menu
        ntscSyncMenuItem                matlab.ui.container.Menu
        palSyncMenuItem                 matlab.ui.container.Menu
        cinemaSyncMenuItem              matlab.ui.container.Menu
        speedMenuItem                   matlab.ui.container.Menu
        slowSpeedMenuItem               matlab.ui.container.Menu
        normalSpeedMenuItem             matlab.ui.container.Menu
        fastSpeedMenuItem               matlab.ui.container.Menu
        xfastSpeedMenuItem              matlab.ui.container.Menu
        exposureMenuItem                matlab.ui.container.Menu
        autoExposureMenuItem            matlab.ui.container.Menu
        fixedExposureMenuItem           matlab.ui.container.Menu
        enterExposureTimeMilliSecondsMenuItem  matlab.ui.container.Menu
        toggleEchoMenuItem              matlab.ui.container.Menu
        closeCR250MenuItem              matlab.ui.container.Menu
        OperationsMenu                  matlab.ui.container.Menu
        takeMeasurementMenuItem         matlab.ui.container.Menu
        retrieveLastMesurementMenuItem  matlab.ui.container.Menu
        saveLastMeasurementMenuItem     matlab.ui.container.Menu
        calibrationTargets              matlab.ui.container.Menu
        calibrationTargetsON            matlab.ui.container.Menu
        calibrationTargetsOFF           matlab.ui.container.Menu
        DeviceInfoMenu                  matlab.ui.container.Menu
        deviceInfoMenuItem              matlab.ui.container.Menu
        spectrumAxes                    matlab.ui.control.UIAxes
        redCalibrationAxes              matlab.ui.control.UIAxes
        greenCalibrationAxes            matlab.ui.control.UIAxes
        blueCalibrationAxes             matlab.ui.control.UIAxes
    end

    % CR250interactiveApp- Demonstrates the usage of the CR250_device driver for
    % controlling the CR250 colorimeter.
    %
    %  History:
    %    April 2025  NPC  Wrote it
    %

    properties (Access = private)
        % The open CR250dev
        theCR250device = [];

        % The last measurement data
        theSpectralSupport = [];
        theSpectrum = [];
    end
    
    methods (Access = private)
        
        function plotLastMeasurement(app)
            bar(app.spectrumAxes, app.theSpectralSupport, app.theSpectrum, 1.0, ...
                'FaceColor', [1 0.5 0.5], ...
                'EdgeColor', [1 0 0], 'LineWidth', 0.5);
            set(app.spectrumAxes, 'XLim', [370 780], 'XTick', 300:25:800);
            set(app.spectrumAxes, 'FontSize', 16);
            grid(app.spectrumAxes, 'on');
        end

        function initializeSpectrumPlot(app)
            set(app.spectrumAxes, 'XLim', [370 800], 'XTick', 300:25:800);
            set(app.spectrumAxes, 'FontSize', 16)
        end

        function disableMenuItemsThatNeedAnOpenCR250(app)
            % Disable all menu items until we have opened the CR250
            set(app.takeMeasurementMenuItem, 'Enable', 'off');
            set(app.retrieveLastMesurementMenuItem, 'Enable', 'off');
            set(app.toggleEchoMenuItem, 'Enable', 'off');
            set(app.syncMenuItem, 'Enable', 'off');
            set(app.speedMenuItem, 'Enable', 'off');
            set(app.deviceInfoMenuItem, 'Enable', 'off');
            set(app.closeCR250MenuItem, 'Enable', 'off');
        end

        function enableAllMenuItems(app)
            % Enable all menu items 
            set(app.takeMeasurementMenuItem, 'Enable', 'on');
            set(app.retrieveLastMesurementMenuItem, 'Enable', 'on');
            set(app.toggleEchoMenuItem, 'Enable', 'on');
            set(app.syncMenuItem, 'Enable', 'on');
            set(app.speedMenuItem, 'Enable', 'on');
            set(app.deviceInfoMenuItem, 'Enable', 'on');
            set(app.closeCR250MenuItem, 'Enable', 'on');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CR250interactiveUIFigure.Name = 'CR250-interactive';
            app.initializeSpectrumPlot();
            
            app.theSpectralSupport = 370:2:800;
            app.theSpectrum = rand([1 numel(app.theSpectralSupport)]);
            app.plotLastMeasurement();
            app.disableMenuItemsThatNeedAnOpenCR250();
        end

        % Menu selected function: takeMeasurementMenuItem
        function takeMeasurement(app, event)
            app.theCR250device.measure();
            [app.theSpectralSupport, app.theSpectrum] = app.theCR250device.retrieveMeasurement();
            app.plotLastMeasurement();
        end

        % Menu selected function: compileMexDriverMenuItem
        function compileMexDriver(app, event)
            CR250device.compileMexDriver();
            app.disableMenuItemsThatNeedAnOpenCR250();
        end

        % Menu selected function: openCR250MenuItem
        function openCR250(app, event)
            app.theCR250device = CR250device();
            app.theCR250device.verbosity = 'min';
            app.enableAllMenuItems();
        end

        % Menu selected function: manualSyncMenuItem
        function setManualSyncMode(app, event)
            app.theCR250device.syncMode = 'Manual';
        end

        % Menu selected function: noneSyncMenuItem
        function setNoneSyncMode(app, event)
            app.theCR250device.syncMode = 'None';
        end

        % Menu selected function: ntscSyncMenuItem
        function setNTSCSyncMode(app, event)
            app.theCR250device.syncMode = 'NTSC';
        end

        % Menu selected function: palSyncMenuItem
        function setPALSyncMode(app, event)
            app.theCR250device.syncMode = 'PAL';
        end

        % Menu selected function: cinemaSyncMenuItem
        function setCINEMASyncMode(app, event)
            app.theCR250device.syncMode = 'CINEMA';
        end

        % Menu selected function: deviceInfoMenuItem
        function CR250info(app, event)
            app.theCR250device.deviceConfig();
        end

        % Menu selected function: toggleEchoMenuItem
        function toggleEcho(app, event)
            app.theCR250device.toggleEcho()
        end

        % Menu selected function: retrieveLastMesurementMenuItem
        function retieveLastMeasuement(app, event)
            [app.theSpectralSupport, app.theSpectrum] = ...
                app.theCR250device.retrieveMeasurement();
             app.plotLastMeasurement();
        end

        % Menu selected function: closeCR250MenuItem
        function closeCR250(app, event)
            app.theCR250device.close();
            app.disableMenuItemsThatNeedAnOpenCR250();
        end

        % Menu selected function: minimumMenu
        function minVerbosity(app, event)
            app.theCR250device.verbosity = 'min';
        end

        % Menu selected function: defaultMenu
        function defaultVerbosity(app, event)
            app.theCR250device.verbosity = 'default';
        end

        % Menu selected function: maxMenu
        function maximumVerbosity(app, event)
            app.theCR250device.verbosity = 'max';
        end

        % Menu selected function: calibrationTargetsON
        function RGCcalibrationTargetsON(app, event)
            set(app.redCalibrationAxes, 'Visible', 'on')
            set(app.greenCalibrationAxes, 'Visible', 'on')
            set(app.blueCalibrationAxes, 'Visible', 'on')
        end

        % Menu selected function: calibrationTargetsOFF
        function RGCcalibrationTargetsOFF(app, event)
            set(app.redCalibrationAxes, 'Visible', 'off')
            set(app.greenCalibrationAxes, 'Visible', 'off')
            set(app.blueCalibrationAxes, 'Visible', 'off')
        end

        % Menu selected function: saveLastMeasurementMenuItem
        function saveLastMeasurement(app, event)
            theSPD = struct(...
                'support', app.theSpectralSupport, ...
                'radiance', app.theSpectrum);
            theMeasurementTimeStamp = datetime('now','TimeZone','local','Format','yyyy-MM-dd@HH-mm');
            theMatFilename = sprintf('CR250meas%s.mat', theMeasurementTimeStamp);
            uisave({'theSPD','theMeasurementTimeStamp'},theMatFilename);
        end

        % Menu selected function: enterfrequencyHzMenuItem
        function queryUserForCustomSyncFrequency(app, event)
            prompt = {'Enter SYNC frequency:'};
            dlgtitle = 'SYNC frequency, [10 Hz - 10 kHz])';
            fieldsize = [1 40];
            definput = {'120'};
            opts.Interpreter = 'none';
            theAnswer = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
            theSyncFrequency = str2num(theAnswer{1});
            app.theCR250device.manualSyncFrequency = theSyncFrequency;
            showFullResponse = true;
            app.theCR250device.retrieveCurrentManualSyncFrequency(showFullResponse);
        end

        % Menu selected function: slowSpeedMenuItem
        function setSlowSpeedMode(app, event)
            app.theCR250device.speedMode = 'Slow';
        end

        % Menu selected function: normalSpeedMenuItem
        function setNormalSpeedMode(app, event)
            app.theCR250device.speedMode = 'Normal';
        end

        % Menu selected function: fastSpeedMenuItem
        function setFastSpeedMode(app, event)
            app.theCR250device.speedMode = 'Fast';
        end

        % Menu selected function: xfastSpeedMenuItem
        function set2XfastSpeedMode(app, event)
            app.theCR250device.speedMode = '2x Fast';
        end

        % Menu selected function: autoExposureMenuItem
        function setAutoExposureMode(app, event)
            app.theCR250device.exposureMode = 'auto'; 
        end

        % Menu selected function: enterExposureTimeMilliSecondsMenuItem
        function queryUserForCustomExposureTime(app, event)
            prompt = {'Enter exposure time:'};
            dlgtitle = 'Exposure time, in milliseconds)';
            fieldsize = [1 40];
            definput = {'500'};
            opts.Interpreter = 'none';
            theAnswer = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
            theFixedExposureTime = str2num(theAnswer{1});
            app.theCR250device.fixedExposureTimeMilliseconds = theFixedExposureTime;
            showFullResponse = true;
            app.theCR250device.retrieveCurrentExposureTime(showFullResponse);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CR250interactiveUIFigure = uifigure('Visible', 'off');
            app.CR250interactiveUIFigure.Name = 'CR250-interactive';
            app.MaintenanceMenu = uimenu(app.CR250interactiveUIFigure);
            app.MaintenanceMenu.Text = 'Maintenance       ';
            app.compileMexDriverMenuItem = uimenu(app.MaintenanceMenu);
            app.compileMexDriverMenuItem.MenuSelectedFcn = createCallbackFcn(app, @compileMexDriver, true);
            app.compileMexDriverMenuItem.Text = 'compile MEX driver';
            app.DeviceConfigMenu = uimenu(app.CR250interactiveUIFigure);
            app.DeviceConfigMenu.Text = 'Device Config       ';
            app.openCR250MenuItem = uimenu(app.DeviceConfigMenu);
            app.openCR250MenuItem.MenuSelectedFcn = createCallbackFcn(app, @openCR250, true);
            app.openCR250MenuItem.Text = 'open CR250';
            app.verbosity = uimenu(app.DeviceConfigMenu);
            app.verbosity.Text = 'verbosity';
            app.minimumMenu = uimenu(app.verbosity);
            app.minimumMenu.MenuSelectedFcn = createCallbackFcn(app, @minVerbosity, true);
            app.minimumMenu.Text = 'minimum';
            app.defaultMenu = uimenu(app.verbosity);
            app.defaultMenu.MenuSelectedFcn = createCallbackFcn(app, @defaultVerbosity, true);
            app.defaultMenu.Text = 'default';
            app.maxMenu = uimenu(app.verbosity);
            app.maxMenu.MenuSelectedFcn = createCallbackFcn(app, @maximumVerbosity, true);
            app.maxMenu.Text = 'max';
            app.syncMenuItem = uimenu(app.DeviceConfigMenu);
            app.syncMenuItem.Text = 'sync mode';
            app.noneSyncMenuItem = uimenu(app.syncMenuItem);
            app.noneSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNoneSyncMode, true);
            app.noneSyncMenuItem.Text = 'none (ONLY for constant light sources)';
            app.manualSyncMenuItem = uimenu(app.syncMenuItem);
            app.manualSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setManualSyncMode, true);
            app.manualSyncMenuItem.Text = 'manual (custom SYNC frequency)';
            app.enterfrequencyHzMenuItem = uimenu(app.manualSyncMenuItem);
            app.enterfrequencyHzMenuItem.MenuSelectedFcn = createCallbackFcn(app, @queryUserForCustomSyncFrequency, true);
            app.enterfrequencyHzMenuItem.Text = 'SYNC frequency';
            app.ntscSyncMenuItem = uimenu(app.syncMenuItem);
            app.ntscSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNTSCSyncMode, true);
            app.ntscSyncMenuItem.Text = 'NTSC (60 Hz)';
            app.palSyncMenuItem = uimenu(app.syncMenuItem);
            app.palSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setPALSyncMode, true);
            app.palSyncMenuItem.Text = 'PAL (50 Hz)';
            app.cinemaSyncMenuItem = uimenu(app.syncMenuItem);
            app.cinemaSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setCINEMASyncMode, true);
            app.cinemaSyncMenuItem.Text = 'CINEMA (48 Hz)';
            app.speedMenuItem = uimenu(app.DeviceConfigMenu);
            app.speedMenuItem.Text = 'speed mode';
            app.slowSpeedMenuItem = uimenu(app.speedMenuItem);
            app.slowSpeedMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setSlowSpeedMode, true);
            app.slowSpeedMenuItem.Text = 'slow';
            app.normalSpeedMenuItem = uimenu(app.speedMenuItem);
            app.normalSpeedMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNormalSpeedMode, true);
            app.normalSpeedMenuItem.Text = 'normal';
            app.fastSpeedMenuItem = uimenu(app.speedMenuItem);
            app.fastSpeedMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setFastSpeedMode, true);
            app.fastSpeedMenuItem.Text = 'fast';
            app.xfastSpeedMenuItem = uimenu(app.speedMenuItem);
            app.xfastSpeedMenuItem.MenuSelectedFcn = createCallbackFcn(app, @set2XfastSpeedMode, true);
            app.xfastSpeedMenuItem.Text = '2 x fast';
            app.exposureMenuItem = uimenu(app.DeviceConfigMenu);
            app.exposureMenuItem.Text = 'exposure mode';
            app.autoExposureMenuItem = uimenu(app.exposureMenuItem);
            app.autoExposureMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setAutoExposureMode, true);
            app.autoExposureMenuItem.Text = 'auto';
            app.fixedExposureMenuItem = uimenu(app.exposureMenuItem);
            app.fixedExposureMenuItem.Text = 'fixed (custom exposure time)';
            app.enterExposureTimeMilliSecondsMenuItem = uimenu(app.fixedExposureMenuItem);
            app.enterExposureTimeMilliSecondsMenuItem.MenuSelectedFcn = createCallbackFcn(app, @queryUserForCustomExposureTime, true);
            app.enterExposureTimeMilliSecondsMenuItem.Text = 'exposure time';
            app.toggleEchoMenuItem = uimenu(app.DeviceConfigMenu);
            app.toggleEchoMenuItem.MenuSelectedFcn = createCallbackFcn(app, @toggleEcho, true);
            app.toggleEchoMenuItem.Text = 'toggleEcho';
            app.closeCR250MenuItem = uimenu(app.DeviceConfigMenu);
            app.closeCR250MenuItem.MenuSelectedFcn = createCallbackFcn(app, @closeCR250, true);
            app.closeCR250MenuItem.Text = 'close CR250';
            app.OperationsMenu = uimenu(app.CR250interactiveUIFigure);
            app.OperationsMenu.Text = 'Operations      ';
            app.takeMeasurementMenuItem = uimenu(app.OperationsMenu);
            app.takeMeasurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @takeMeasurement, true);
            app.takeMeasurementMenuItem.Text = 'measure spectrum';
            app.retrieveLastMesurementMenuItem = uimenu(app.OperationsMenu);
            app.retrieveLastMesurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @retieveLastMeasuement, true);
            app.retrieveLastMesurementMenuItem.Text = 'retrieve last mesurement';
            app.saveLastMeasurementMenuItem = uimenu(app.OperationsMenu);
            app.saveLastMeasurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @saveLastMeasurement, true);
            app.saveLastMeasurementMenuItem.Text = 'save last measurement';
            app.calibrationTargets = uimenu(app.OperationsMenu);
            app.calibrationTargets.Text = 'RGB calibration targets';
            app.calibrationTargetsON = uimenu(app.calibrationTargets);
            app.calibrationTargetsON.MenuSelectedFcn = createCallbackFcn(app, @RGCcalibrationTargetsON, true);
            app.calibrationTargetsON.Text = 'ON';
            app.calibrationTargetsOFF = uimenu(app.calibrationTargets);
            app.calibrationTargetsOFF.MenuSelectedFcn = createCallbackFcn(app, @RGCcalibrationTargetsOFF, true);
            app.calibrationTargetsOFF.Text = 'OFF';
            app.DeviceInfoMenu = uimenu(app.CR250interactiveUIFigure);
            app.DeviceInfoMenu.Text = 'Device Info';
            app.deviceInfoMenuItem = uimenu(app.DeviceInfoMenu);
            app.deviceInfoMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CR250info, true);
            app.deviceInfoMenuItem.Text = 'CR250info';
            app.blueCalibrationAxes = uiaxes(app.CR250interactiveUIFigure);
            app.blueCalibrationAxes.XTick = [];
            app.blueCalibrationAxes.YTick = [];
            app.greenCalibrationAxes = uiaxes(app.CR250interactiveUIFigure);
            app.greenCalibrationAxes.XTick = [];
            app.greenCalibrationAxes.YTick = [];
            app.redCalibrationAxes = uiaxes(app.CR250interactiveUIFigure);
            app.redCalibrationAxes.XTick = [];
            app.redCalibrationAxes.YTick = [];
            app.spectrumAxes = uiaxes(app.CR250interactiveUIFigure);
            xlabel(app.spectrumAxes, 'wavelength (nm)')
            ylabel(app.spectrumAxes, 'radiance')
            zlabel(app.spectrumAxes, 'Z')
            app.spectrumAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.CR250interactiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR250interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CR250interactiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CR250interactiveUIFigure)
        end
    end
end